classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        CountryLabel               matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        StateorRegoinLabel         matlab.ui.control.Label
        StateorRegoinListBox       matlab.ui.control.ListBox
        AveragedofdaysSliderLabel  matlab.ui.control.Label
        AveragedofdaysSlider       matlab.ui.control.Slider
        DatatoplotButtonGroup      matlab.ui.container.ButtonGroup
        CasesButton                matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        BothButton                 matlab.ui.control.RadioButton
        OptionsButtonGroup         matlab.ui.container.ButtonGroup
        CumulativeButton           matlab.ui.control.RadioButton
        DailyButton                matlab.ui.control.RadioButton
    end


    properties (Access = private)
        data % Description
        date
        mean
        cases
        death
        meancases
        meandeaths
        cumcases
        cumdeaths
        showdeath
        showcases
        country
        stats
        map
    end

    methods (Access = private)
    
        function plotdata(app)
            cla(app.UIAxes,'reset');
            app.UIAxes.XLim = [0 length(app.date)+1];
            fig = app.UIAxes;
            fig.XTick = [0:15:length(app.date)];
            fig.XTickLabel = app.date(1:15:end);
            xtickangle(app.UIAxes,45);
            if app.showcases
                yyaxis(app.UIAxes,'left');
                app.UIAxes.YGrid = true;
                bar(app.UIAxes,app.meancases);
                app.UIAxes.ColorOrder = [0.00,0.45,0.74];
            end
            if app.showdeath
                yyaxis(app.UIAxes,'right');
                app.UIAxes.YGrid = true;
                plot(app.UIAxes,app.meandeaths,'r');
            end
        end
        
        function average(app)
            if app.DailyButton.Value
                app.meancases = movmean(app.cases,app.mean);
                app.meandeaths = movmean(app.death,app.mean);
            end
            if app.CumulativeButton.Value
                app.meancases = movmean(app.cumcases,app.mean);
                app.meandeaths = movmean(app.cumdeaths,app.mean);
            end
        end
        
        function cum(app)
            if app.CumulativeButton.Value
                app.cumcases = cumsum(app.meancases);
                app.cumdeaths = cumsum(app.meandeaths);
            end
        end
        
        function setcountries(app)
            prev = [1];
            app.country = {};
            temp = {};
            load covid_data;
            for count = [2:length(covid_data(2:end,1))]
                if ~strcmp(covid_data{count,1},prev)
                    app.country{end+1} = covid_data{count,1};
                    if ischar(prev)
                        app.map(prev) = temp;
                        temp = {};
                    end
                else
                    if ~isempty(covid_data{count,2})
                        temp{end+1} = covid_data{count,2};
                    end
                end
                prev = covid_data{count,1};
            end
        end
        
        function setstate(app)
            if isKey(app.map , app.CountryListBox.Value)
                app.StateorRegoinListBox.Items = app.map(app.CountryListBox.Value);
            end
        end
        
        
        function setdata(app)
            app.data = app.stats(char([app.CountryListBox.Value, app.StateorRegoinListBox.Value]));
            app.cases = app.data(1:2:end);
            app.death = app.data(2:2:end);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load covid_data;
            app.map = containers.Map;
            app.stats = containers.Map;
            app.setcountries();
            app.CountryListBox.Items = app.country;
            app.CountryListBox.Value = app.country{1};
            app.setstate();
            for ii = [2:length(covid_data(2:end,1))]
                app.stats(char([covid_data{ii,1} ,covid_data{ii,2}])) = [covid_data{ii,3:end}];
            end
            app.setdata();
            app.date = covid_data(1,3:end);
            app.mean = app.AveragedofdaysSlider.Value;
            app.meancases = movmean(app.cases,app.mean);
            app.meandeaths = movmean(app.death,app.mean);
            app.cumcases = cumsum(app.cases);
            app.cumdeaths = cumsum(app.death);
            app.showdeath = app.DeathsButton.Value || app.BothButton.Value;
            app.showcases = app.CasesButton.Value || app.BothButton.Value;
            app.cum();
            app.average();
            app.plotdata();
        end

        % Value changed function: StateorRegoinListBox
        function StateorRegoinListBoxValueChanged(app, event)
            app.setdata();
            app.average();
            app.plotdata();
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            app.setstate();
            if ~isempty(app.StateorRegoinListBox.Items)
                app.StateorRegoinListBox.Value = app.StateorRegoinListBox.Items(1);
            end
            app.setdata();
            app.average();
            app.plotdata();
        end

        % Selection changed function: DatatoplotButtonGroup
        function DatatoplotButtonGroupSelectionChanged(app, event)
            app.showdeath = app.DeathsButton.Value || app.BothButton.Value;
            app.showcases = app.CasesButton.Value || app.BothButton.Value;
            app.average();
            app.plotdata();
        end

        % Value changing function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanging(app, event)
            app.mean = event.Value;
            app.average();
            app.plotdata();
        end

        % Selection changed function: OptionsButtonGroup
        function OptionsButtonGroupSelectionChanged(app, event)
            app.cum();
            app.average();
            app.plotdata();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Daily number of cases and deaths')
            xlabel(app.UIAxes, 'date')
            ylabel(app.UIAxes, 'cases')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.XTickLabel = '';
            app.CountryLabel = uilabel(app.UIFigure);
            app.CountryLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateorRegoinLabel = uilabel(app.UIFigure);
            app.StateorRegoinLabel.Text = 'State or Regoin:';
            app.StateorRegoinListBox = uilistbox(app.UIFigure);
            app.StateorRegoinListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegoinListBoxValueChanged, true);
            app.AveragedofdaysSliderLabel = uilabel(app.UIFigure);
            app.AveragedofdaysSliderLabel.Text = 'Averaged # of days';
            app.AveragedofdaysSlider = uislider(app.UIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.ValueChangingFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanging, true);
            app.AveragedofdaysSlider.Value = 1;
            app.DatatoplotButtonGroup = uibuttongroup(app.UIFigure);
            app.DatatoplotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoplotButtonGroupSelectionChanged, true);
            app.DatatoplotButtonGroup.Title = 'Data to plot';
            app.CasesButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.DeathsButton.Value = true;
            app.BothButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.BothButton.Text = 'Both';
            app.BothButton.Value = true;
            app.OptionsButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionsButtonGroupSelectionChanged, true);
            app.OptionsButtonGroup.Title = 'Options';
            app.CumulativeButton = uiradiobutton(app.OptionsButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionsButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.DailyButton.Value = true;
        end
    end

    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end